classdef allModels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputsPanel                     matlab.ui.container.Panel
        SeverityscoreDropDownLabel      matlab.ui.control.Label
        SeverityScore                   matlab.ui.control.DropDown
        Input1                          matlab.ui.control.NumericEditField
        Input2                          matlab.ui.control.NumericEditField
        Input3                          matlab.ui.control.NumericEditField
        Input4                          matlab.ui.control.NumericEditField
        Input5                          matlab.ui.control.NumericEditField
        VarName1                        matlab.ui.control.EditField
        VarName2                        matlab.ui.control.EditField
        VarName3                        matlab.ui.control.EditField
        VarName4                        matlab.ui.control.EditField
        VarName5                        matlab.ui.control.EditField
        UnitsLabel                      matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        TreatmenttypeLabel              matlab.ui.control.Label
        TreatmentType                   matlab.ui.control.DropDown
        InitialscoreEditFieldLabel      matlab.ui.control.Label
        InitialScore                    matlab.ui.control.NumericEditField
        PredictionPanel                 matlab.ui.container.Panel
        PredictionGraph                 matlab.ui.control.UIAxes
        ImprovementEditFieldLabel       matlab.ui.control.Label
        Improvement                     matlab.ui.control.EditField
        ModelPerformancePanel           matlab.ui.container.Panel
        AccuracyEditFieldLabel          matlab.ui.control.Label
        Accuracy                        matlab.ui.control.NumericEditField
        RMSEEditFieldLabel              matlab.ui.control.Label
        RMSE                            matlab.ui.control.NumericEditField
        SystemicTherapyPredictionLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set starting (oSCORAD) input names
            app.VarName1.Value = 'VEGF';
            app.VarName2.Value = 'IL7';
            app.VarName3.Value = 'TNFa';
            app.VarName4.Value = 'MIP1b';
            app.VarName5.Value = 'IL10';
            
            % Set starting (oSCORAD) performance values
            app.Accuracy.Value = 0.724;
            app.RMSE.Value = 8.15;
            
            % Set starting (oSCORAD) axes limits 
            xlim(app.PredictionGraph,[0 24]);
            ylim(app.PredictionGraph,[0 83]);
            
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            % Extract the inputs
            biomarkers = [app.Input1.Value; app.Input2.Value; app.Input3.Value; app.Input4.Value; app.Input5.Value;];
            isAza = isequal(app.TreatmentType.Value, 'AZA');
            initScore = app.InitialScore.Value;
            
            % Calculate the predictions
            value = app.SeverityScore.Value;
            if isequal(value, 'oSCORAD')
                [weekPredictions, imprStatus] = oSCORADPred(biomarkers, isAza, initScore);
                maxScore = 83;
            elseif isequal(value, 'SCORAD')
                [weekPredictions, imprStatus] = totSCORADPred(biomarkers, isAza, initScore);
                maxScore = 103;
            elseif isequal(value, 'EASI')
                [weekPredictions, imprStatus] = easiPred(biomarkers, isAza, initScore);
                maxScore = 72;
            else
                error('Error determining score type.');
            end
            
            % Display the improvement status
            try
                maxWeeks = 24;
                plot(app.PredictionGraph, [0:maxWeeks], weekPredictions, 'x');
                xlim(app.PredictionGraph,[0 maxWeeks]);
                ylim(app.PredictionGraph,[0 maxScore]);
                app.Improvement.Value = imprStatus;
            catch
                error('Invalid inputs.');
            end
        end

        % Value changed function: SeverityScore
        function SeverityScoreValueChanged(app, event)
            value = app.SeverityScore.Value;
            
            % Set graph y axis
            app.PredictionGraph.YLabel.String = value;
            
            % Set input labels and accuracy
            if isequal(value, 'oSCORAD')
                app.VarName1.Value = 'VEGF';
                app.VarName2.Value = 'IL7';
                app.VarName3.Value = 'TNFa';
                app.VarName4.Value = 'MIP1b';
                app.VarName5.Value = 'IL10';
                
                app.Accuracy.Value = 0.724;
                app.RMSE.Value = 8.15;
                
                ylim(app.PredictionGraph,[0 83]);
                
            elseif isequal(value, 'SCORAD')
                app.VarName1.Value = 'VEGF';
                app.VarName2.Value = 'IL18';
                app.VarName3.Value = 'IL7';
                app.VarName4.Value = 'CTACK';
                app.VarName5.Value = 'IL10';
                
                app.Accuracy.Value = 0.645;
                app.RMSE.Value = 10.50;
                
                ylim(app.PredictionGraph,[0 103]);
                
            elseif isequal(value, 'EASI')
                app.VarName1.Value = 'IL7';
                app.VarName2.Value = 'TNFa';
                app.VarName3.Value = 'IL31';
                app.VarName4.Value = 'MIP1b';
                app.VarName5.Value = 'IL10';
                
                app.Accuracy.Value = 0.803;
                app.RMSE.Value = 6.33;
                
                ylim(app.PredictionGraph,[0 72]);
                
            else
                app.VarName1.Value = 'Error';
                app.VarName2.Value = 'Error';
                app.VarName3.Value = 'Error';
                app.VarName4.Value = 'Error';
                app.VarName5.Value = 'Error';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.SeverityscoreDropDownLabel = uilabel(app.InputsPanel);
            app.SeverityscoreDropDownLabel.Text = 'Severity score';
            app.SeverityScore = uidropdown(app.InputsPanel);
            app.SeverityScore.Items = {'oSCORAD', 'SCORAD', 'EASI'};
            app.SeverityScore.ValueChangedFcn = createCallbackFcn(app, @SeverityScoreValueChanged, true);
            app.SeverityScore.Value = 'oSCORAD';
            app.Input1 = uieditfield(app.InputsPanel, 'numeric');
            app.Input2 = uieditfield(app.InputsPanel, 'numeric');
            app.Input3 = uieditfield(app.InputsPanel, 'numeric');
            app.Input4 = uieditfield(app.InputsPanel, 'numeric');
            app.Input5 = uieditfield(app.InputsPanel, 'numeric');
            app.VarName1 = uieditfield(app.InputsPanel, 'text');
            app.VarName1.Editable = 'off';
            app.VarName1.Value = 'VEGF';
            app.VarName2 = uieditfield(app.InputsPanel, 'text');
            app.VarName2.Editable = 'off';
            app.VarName2.Value = 'IL7';
            app.VarName3 = uieditfield(app.InputsPanel, 'text');
            app.VarName3.Editable = 'off';
            app.VarName3.Value = 'TNFa';
            app.VarName4 = uieditfield(app.InputsPanel, 'text');
            app.VarName4.Editable = 'off';
            app.VarName4.Value = 'MIP1b';
            app.VarName5 = uieditfield(app.InputsPanel, 'text');
            app.VarName5.Editable = 'off';
            app.VarName5.Value = 'IL10';
            app.UnitsLabel = uilabel(app.InputsPanel);
            app.UnitsLabel.Text = 'All values should be entered in pg/ml.';
            app.CalculateButton = uibutton(app.InputsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TreatmenttypeLabel = uilabel(app.InputsPanel);
            app.TreatmenttypeLabel.Text = 'Treatment type';
            app.TreatmentType = uidropdown(app.InputsPanel);
            app.TreatmentType.Items = {'AZA', 'MTX'};
            app.TreatmentType.Value = 'AZA';
            app.InitialscoreEditFieldLabel = uilabel(app.InputsPanel);
            app.InitialscoreEditFieldLabel.Text = 'Initial score';
            app.InitialScore = uieditfield(app.InputsPanel, 'numeric');
            app.PredictionPanel = uipanel(app.UIFigure);
            app.PredictionPanel.Title = 'Prediction';
            app.PredictionGraph = uiaxes(app.PredictionPanel);
            xlabel(app.PredictionGraph, 'Week number')
            ylabel(app.PredictionGraph, 'oSCORAD')
            app.ImprovementEditFieldLabel = uilabel(app.PredictionPanel);
            app.ImprovementEditFieldLabel.Text = 'Improvement';
            app.Improvement = uieditfield(app.PredictionPanel, 'text');
            app.Improvement.Editable = 'off';
            app.ModelPerformancePanel = uipanel(app.UIFigure);
            app.ModelPerformancePanel.Title = 'Model Performance';
            app.AccuracyEditFieldLabel = uilabel(app.ModelPerformancePanel);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.Accuracy = uieditfield(app.ModelPerformancePanel, 'numeric');
            app.Accuracy.Editable = 'off';
            app.RMSEEditFieldLabel = uilabel(app.ModelPerformancePanel);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSE = uieditfield(app.ModelPerformancePanel, 'numeric');
            app.RMSE.Editable = 'off';
            app.SystemicTherapyPredictionLabel = uilabel(app.UIFigure);
            app.SystemicTherapyPredictionLabel.Text = 'Systemic Therapy Prediction';
        end
    end

    methods (Access = public)

        % Construct app
        function app = allModels

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end